classdef DAST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        TestTab                         matlab.ui.container.Tab
        W12Button                       matlab.ui.control.StateButton
        NextlistButton                  matlab.ui.control.Button
        W11Button                       matlab.ui.control.StateButton
        W10Button                       matlab.ui.control.StateButton
        W9Button                        matlab.ui.control.StateButton
        W8Button                        matlab.ui.control.StateButton
        W7Button                        matlab.ui.control.StateButton
        W6Button                        matlab.ui.control.StateButton
        W5Button                        matlab.ui.control.StateButton
        W4Button                        matlab.ui.control.StateButton
        W3Button                        matlab.ui.control.StateButton
        W2Button                        matlab.ui.control.StateButton
        W1Button                        matlab.ui.control.StateButton
        PauseButton                     matlab.ui.control.StateButton
        MarkallButton                   matlab.ui.control.StateButton
        Instructions                    matlab.ui.control.Label
        Sentences                       matlab.ui.control.Label
        currentSent                     matlab.ui.control.Label
        currentList                     matlab.ui.control.Label
        ContinueButton                  matlab.ui.control.Button
        DanskStningstestLabel           matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        SettingsTab                     matlab.ui.container.Tab
        SettingsPanelinSettingsTab      matlab.ui.container.Panel
        PlaybackmodeButtonGroup         matlab.ui.container.ButtonGroup
        MultiButton                     matlab.ui.control.RadioButton
        MonoButton                      matlab.ui.control.RadioButton
        StereoButton                    matlab.ui.control.RadioButton
        PresetButtonGroup               matlab.ui.container.ButtonGroup
        SavePresentButton               matlab.ui.control.Button
        currentPresent                  matlab.ui.control.EditField
        OpenPresentButton               matlab.ui.control.Button
        PlaybackmethodButtonGroup       matlab.ui.container.ButtonGroup
        ChannelsInEditField             matlab.ui.control.EditField
        ChannelsInEditFieldLabel        matlab.ui.control.Label
        BasicPlaybackButton             matlab.ui.control.RadioButton
        AudioToolboxButton              matlab.ui.control.RadioButton
        PsychtoolboxButton              matlab.ui.control.RadioButton
        DriverDropDownLabel             matlab.ui.control.Label
        DriverDropDown                  matlab.ui.control.DropDown
        ChannelsOutEditField            matlab.ui.control.EditField
        ChannelsOutEditFieldLabel       matlab.ui.control.Label
        MaskersettingsButtonGroup       matlab.ui.container.ButtonGroup
        DurationBeforeEditField         matlab.ui.control.EditField
        DurationbeforeandaftersentencesLabel  matlab.ui.control.Label
        secLabel                        matlab.ui.control.Label
        IntermittentButton              matlab.ui.control.RadioButton
        ContinuousButton                matlab.ui.control.RadioButton
        ScoringtypeButtonGroup          matlab.ui.container.ButtonGroup
        WordByWordButton                matlab.ui.control.RadioButton
        KeywordBasedButton              matlab.ui.control.RadioButton
        PresentationmodeButtonGroup     matlab.ui.container.ButtonGroup
        AudioVisualPresentationButton   matlab.ui.control.RadioButton
        AudioPresentationButton         matlab.ui.control.RadioButton
        CalibrationButtonGroup          matlab.ui.container.ButtonGroup
        ImportequalizationfilterButton  matlab.ui.control.Button
        FilterEditField                 matlab.ui.control.EditField
        FilterEditFieldLabel            matlab.ui.control.Label
        PlaycalibrationfileButton       matlab.ui.control.StateButton
        MeasuredLabel                   matlab.ui.control.Label
        CalibrationMeasuredEditField    matlab.ui.control.EditField
        dBZLabel                        matlab.ui.control.Label
        ResulttypeButtonGroup           matlab.ui.container.ButtonGroup
        SpeechReceptionThresholdButton  matlab.ui.control.RadioButton
        PercentCorrectButton            matlab.ui.control.RadioButton
        Panel                           matlab.ui.container.Panel
        currentAudioDir                 matlab.ui.control.EditField
        AudiopathEditFieldLabel         matlab.ui.control.Label
        ChooseAudioPathButton           matlab.ui.control.Button
        AudioSettingsTabs               matlab.ui.container.TabGroup
        QuickstartTab                   matlab.ui.container.Tab
        RandomizewithinlistCheckBox     matlab.ui.control.CheckBox
        MaskerlevelEditField            matlab.ui.control.EditField
        MaskerlevelEditFieldLabel       matlab.ui.control.Label
        TargetlevelEditField            matlab.ui.control.EditField
        TargetlevelEditFieldLabel       matlab.ui.control.Label
        MaskertypeDropDown              matlab.ui.control.DropDown
        MaskertypeDropDownLabel         matlab.ui.control.Label
        ListnumberDropDown              matlab.ui.control.DropDown
        ListnumberDropDownLabel         matlab.ui.control.Label
        TalkerDropDown                  matlab.ui.control.DropDown
        TalkerDropDownLabel             matlab.ui.control.Label
        dBSPLLabel_2                    matlab.ui.control.Label
        dBSPLLabel                      matlab.ui.control.Label
        AdvancedTab                     matlab.ui.container.Tab
        StartinglistDropDown            matlab.ui.control.DropDown
        StartinglistDropDownLabel       matlab.ui.control.Label
        UploadedCSVCheckBox             matlab.ui.control.CheckBox
        PleaseuploadacsvfilewiththecorrectformatLabel  matlab.ui.control.Label
        CSVfileEditField                matlab.ui.control.EditField
        CSVfileEditFieldLabel           matlab.ui.control.Label
        UploadButton                    matlab.ui.control.Button
        BasicinformationPanel           matlab.ui.container.Panel
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        ParticipantIDEditField          matlab.ui.control.EditField
        ParticipantIDEditFieldLabel     matlab.ui.control.Label
        TesterIDEditField               matlab.ui.control.EditField
        TesterIDEditFieldLabel          matlab.ui.control.Label
    end


    properties (Access = public)

        % % Definition of global variables
        % % to use across different functions

        %#ok<*ST2NM> use str2num, because str2double gives nan

        % Session settings
        Date;
        targetDir; maskerDir; 
        targetFile; maskerFile; mixtureFile;
        resultName; resultDir; resultRecordingsDir; 
        resultFullfile; targetFullfile; maskerFullfile;
        lists; listTot; list_iteration;
        listNum; talker; maskerType; targetLevel; maskerLevel; 
        Fs = 48e3; % Fs = 48 kHz

        % Mode/Type/Method for experimental settings
        ResultType;
        PresentationMode;
        MaskerSettings;
        ScoringType;
        playbackMethod;
        playbackMode;

        % Masker settings
        DurationBefore;
        hannLen = 50e-3*48e3; % 50ms of ramping up and down of the masker (i.e., based on 48 kHz sampling freq)
        hannWindow = hann(2*50e-3*48e3);
  
        % Calibration
        equalizationFilter;
        calibrationMeasuredLevel;
        dBFSRef = -26;
        dBSPLRef = 95; % 95 dB SPL is equivalent to -26 dB FS

        % Playback method
        playbackDriver; 
        playbackChannelsOut; playbackChannelsIn;
        playbackObjectPlayer; playbackObjectRecorder; 
        playbackObjectFileReader; playbackTemporaryFile; 
        playbackObjectFileWriter;
        playbackBuffer = 2048; % 16384
        recordingFlag;

        % Current sentence
        sentences; sentencesFull; sentencesDir; sent; sentTot; 
        sentText; iteration; 

        % Results
        scores; totalScore;

        % Buttons
        totButtons = 12;
        Keywords; KeywordsButtons = []; activeButtons;
        
    end

    % Functions that save/play/read data outside of the app
    methods (Access = public)

        function playSentence(app)

            % Read the target file
            targetSignal = audioread(app.targetFullfile);

            % Add padding to target, if relevant
            if app.DurationBefore > 0.5
                targetSignal = [zeros((app.DurationBefore-0.5)*app.Fs,1); targetSignal(:)];
            end

            % Read info about masker
            maskerInfo = audioinfo(app.maskerFullfile);

            % Read in random segment of masker
            randSample = round((maskerInfo.TotalSamples-length(targetSignal))*rand); % TODO Save this info in results???
            maskerSignal = audioread(app.maskerFullfile,...
                [randSample,randSample+length(targetSignal)-1]);
           
            % Add ramping to masker
            maskerSignal(1:app.hannLen) = app.hannWindow(1:app.hannLen).*maskerSignal(1:app.hannLen);
            maskerSignal(end-app.hannLen+1:end) = app.hannWindow(app.hannLen+1:end).*maskerSignal(end-app.hannLen+1:end);

            % Replicate vector as needed
            switch app.playbackMode
                case 'mono'
                    % do nothing
                case 'stereo'
                    targetSignal = repmat(targetSignal,1,2);
                    maskerSignal = repmat(maskerSignal,1,2);
                case 'multi'
%                     targetSignal = repmat(targetSignal,1,TODO);
%                     maskerSignal = repmat(maskerSignal,1,TODO);
            end

            % Apply equalization filter, if relevant
            if ~isempty(app.equalizationFilter)
                for cc = 1:size(app.equalizationFilter,2)
                    targetSignal(:,cc) = filter(app.equalizationFilter(:,cc),1,targetSignal(:,cc));
                    maskerSignal(:,cc) = filter(app.equalizationFilter(:,cc),1,maskerSignal(:,cc));
                end
            end

            % Scale the digital levels in each channel to get the desired physical level
            for cc = 1:size(targetSignal,2)
                targetGain = (app.targetLevel-app.dBSPLRef) + (app.dBSPLRef-app.calibrationMeasuredLevel(cc)); % calculate gain (i.e., sentence correction + calibration correction)
                targetSignal(:,cc) = targetSignal(:,cc)*(10^(targetGain/20));
            end
            for cc = 1:size(maskerSignal,2) % NOTE assumes maskerFullfile has an long-term rms scaled to -26 dB FS to match the DAST material
                maskerGain = (app.maskerLevel-app.dBSPLRef) + (app.dBSPLRef-app.calibrationMeasuredLevel(cc));
                maskerSignal(:,cc) = maskerSignal(:,cc)*(10^(maskerGain/20));
            end
            % 20*log10(rms(targetSignal(app.DurationBefore*app.Fs:end-app.DurationBefore*app.Fs))/rms(maskerSignal(app.DurationBefore*app.Fs:end-app.DurationBefore*app.Fs)))

            % Combine target and masker
            mixtureSignal = targetSignal + maskerSignal;

            % TODO Keep these separate somehow to interface with
            % ChannelsOut vector so that the noise can be spatially
            % separated?

            % Play sound
            switch app.playbackMethod

                case 'Basic'
                    
                    sound(mixtureSignal,app.Fs);
                    pause(4);
                    app.ContinueButton.Enable = "on";

                case 'AudioToolbox' % Audio Toolbox

                        % Play via Audio Toolbox
                        soundViaAudioToolbox(app,mixtureSignal);

                        % Record via Audio Toolbox, if relevant
                        if ~isempty(app.playbackChannelsIn)
                            recordViaAudioToolbox(app);
                        end
                      
                case 'Psychtoolbox'

                    % TODO

            end
        end

        function updateInformation(app)
            app.iteration = app.iteration + 1;

            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab

                audioFilesInFolder = dir(fullfile(app.targetDir,'*.wav'));
                app.sentTot = numel(audioFilesInFolder); % total num of sentences in folder

            elseif app.AudioSettingsTabs.SelectedTab == app.AdvancedTab

                % Get current sentence number and text (from list)
                app.listNum = app.lists(app.list_iteration);
                app.sentences = app.sentencesFull(app.sentencesFull.List == app.listNum,:);
                app.sentTot = height(app.sentences); % total num of sentences per csv list

                % Get list/sentence individual settings
                app.talker = char(app.sentences.Talker(app.iteration)); % current talker
                app.maskerType = char(app.sentences.Masker(app.iteration)); % current masker

                % File locations:
                app.targetDir = fullfile(app.currentAudioDir.Value,'Speech',...
                    app.talker); % targets dir
                app.maskerDir = fullfile(app.currentAudioDir.Value,'Noise'); % masker dir

                app.targetLevel = app.sentences.TargetLevel(app.iteration); % current target level
                app.maskerLevel = app.sentences.MaskerLevel(app.iteration); % current masker level

            end

            % Get current sentence number and text
            app.sent = app.sentences.Number(app.iteration); % current sentence
            app.sentText = app.sentences.Sentence(app.iteration); % current text sentence

            % Update fields
            app.currentSent.Text = sprintf('Sentence %i (%i/%i)', app.sent, app.iteration, app.sentTot);
            app.currentList.Text = sprintf('List %i (%i/%i)', app.listNum, app.list_iteration, app.listTot);

            % ----------------------------------------------------

            % Buttons: keywords or word-based
            buttonSz = 8; % initial button size (wo. words)
            letterSz = 12; % increment for each letter in word
            wordSpacing = 4; % spacing between buttons (words)

            % make all buttons invisible
            for button = 1:app.totButtons
                app.(sprintf('W%iButton', button)).Visible = "off";
            end

            str = string(app.sentences.Sentence(app.iteration));
            Words = strsplit(str);
            nWords = numel(Words);
            nLetters = strlength(str);
            app.Keywords = string(app.sentences.Keywords(app.iteration));

            % initializing initial button position
            centerGrid = app.Sentences.Position(1)+ app.Sentences.Position(3)/2;
            totalSentWidth = buttonSz + wordSpacing + nLetters*(letterSz);
            initPosition = centerGrid - totalSentWidth/2;
            totalSize = initPosition;

            for i = 1:nWords

                % --- displaying each word onto buttons
                app.(sprintf('W%iButton', i)).Visible = "on";
                app.(sprintf('W%iButton', i)).Text = Words(i);

                % Keywords: find in which button and bold text + saving
                if contains(Words(i),strsplit(app.Keywords,', '),'IgnoreCase',true)
                    app.(sprintf('W%iButton', i)).FontWeight = 'bold';
                    app.KeywordsButtons = [app.KeywordsButtons i];
                end

                % changing width (Pos 3) ---
                app.(sprintf('W%iButton', i)).Position(3) = buttonSz+(letterSz*strlength(Words(i)));

                % --- spacing and locating each button (Pos 1) ---
                app.(sprintf('W%iButton', i)).Position(1) = totalSize;
                % current size + width of last button + space between words
                totalSize = totalSize + app.(sprintf('W%iButton', i)).Position(3) + wordSpacing;

            end

            % Disable non-keyword buttons (when keyword-based scoring)
            if app.ScoringType == "Keyword-based"
                for m = 1:app.totButtons
                    if ~any(app.KeywordsButtons == m)
                        app.(sprintf('W%iButton', m)).Enable = "off";
                    end
                end
            end

            % ----------------------------------------------------

            % Target and masker filenames
            app.targetFile = sprintf('%04d.wav', app.sent);
            app.maskerFile = strcat(app.maskerType, '.wav');
            app.targetFullfile = fullfile(app.targetDir, app.targetFile);
            app.maskerFullfile = fullfile(app.maskerDir, app.maskerFile);

        end

    end

    % Functions that only need access to data within the app
    methods (Access = private)

        function disableButtons_initialState(app)

            % User input enabled
            app.StartButton.Enable = "on";
            app.BasicinformationPanel.Enable = "on";
            app.Panel.Enable = "on";
            app.SettingsPanelinSettingsTab.Enable = "on";

            % Test tab disabled
            app.PauseButton.Enable = "off";
            app.StopButton.Enable = "off";
            app.NextlistButton.Enable = "off";
            app.MarkallButton.Enable = "off";

            app.KeywordsButtons =[];
            app.MarkallButton.Value = 0;
            app.PauseButton.Value = 0;

            for i = 1:app.totButtons
                app.(sprintf('W%iButton', i)).Visible = "off";
                app.(sprintf('W%iButton', i)).Value = 0;
                app.(sprintf('W%iButton', i)).FontWeight = 'normal';
            end

            app.MarkallButton.Visible = "off";
            app.NextlistButton.Visible = "off";
            app.Sentences.Visible = "off";
            app.Instructions.Visible = "off";
            app.currentList.Visible = "off";
            app.currentSent.Visible = "off";

            app.ContinueButton.Enable = "off";

        end

        function enableButtons_testState(app)

            % User input disabled
            app.StartButton.Enable = "off";
            app.BasicinformationPanel.Enable = "off";
            app.Panel.Enable = "off";
            app.SettingsPanelinSettingsTab.Enable = "off";

            % Test tab enabled
            app.PauseButton.Enable = "on";
            app.StopButton.Enable = "on";
            app.MarkallButton.Enable = "on";

            for i = 1:app.totButtons
                app.(sprintf('W%iButton', i)).Visible = "on";
                app.(sprintf('W%iButton', i)).Enable = "on";

            end
            app.MarkallButton.Visible = "on";
            app.NextlistButton.Visible = "off";
            app.Sentences.Visible = "on";
            app.Sentences.Text = 'Sentence';
            app.Sentences.FontSize = 14;
            app.Instructions.Visible = "on";
            app.currentList.Visible = "on";
            app.currentSent.Visible = "on";

        end

        function releaseAudioObjs(app)

            % Avoid crashing by releasing all existing audio objects
            clear sound

            if app.recordingFlag == true
                app.recordingFlag = false;
            end
            if ~isempty(app.playbackObjectPlayer)
                release(app.playbackObjectPlayer);
            end
            if ~isempty(app.playbackObjectFileReader)
                release(app.playbackObjectFileReader);
            end
            if exist(app.playbackTemporaryFile,'file')
                delete(app.playbackTemporaryFile);
            end
            if ~isempty(app.playbackObjectRecorder)
                release(app.playbackObjectRecorder);
            end
            if ~isempty(app.playbackObjectFileWriter)
                release(app.playbackObjectFileWriter);
            end
        end

        function getSettings(app)

            % Shuffle randomization ---> TODO KMJR: Is there a better place to put this? It only needs to happen once at startup.
            rng('shuffle');

            % ------------------------------------------------------------
            % SESSION SETTINGS

            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab
                % If quick start, read from drop down menu
                app.listNum = double(string(erase(app.ListnumberDropDown.Value, "List")));
                app.talker = app.TalkerDropDown.Value;
                app.maskerType = app.MaskertypeDropDown.Value;
                if app.maskerType == "SSN"
%                     % TODO if SSN selected, choose correct file per talker
%                     whichMasker = strcat(app.maskerType, '_', app.talker);
                    % for now:
                    whichMasker = "SSN_S";
                    app.maskerType = whichMasker;
                end
                app.targetLevel = double(string(app.TargetlevelEditField.Value));
                app.maskerLevel = double(string(app.MaskerlevelEditField.Value));

                % File locations:
                app.targetDir = fullfile(app.currentAudioDir.Value,'Speech',...
                    app.talker, strcat("List ", num2str(app.listNum))); % targets dir
                app.maskerDir = fullfile(app.currentAudioDir.Value,'Noise'); % masker dir

                app.sentences = readtable(fullfile(pwd, 'files', 'DAST-sentences.xlsx')); % file with DAST sentences

                app.listTot = 1;

            end

            % ------------------------------------------------------------
            % Saving settings

            % Saving file location
            app.resultDir = fullfile(pwd, 'results'); % results dir
            if ~exist(app.resultDir, 'dir')
                mkdir(app.resultDir);
            end

            app.resultName = app.FilenameEditField.Value; % results file name
            app.resultFullfile = fullfile(app.resultDir, [app.resultName, '.csv']);

            % if csv file already exists (duplicating file name)
            newName = app.resultName;
            for i = 1:30
                if isfile(app.resultFullfile)
                    newName = strcat(app.resultName, '.', num2str(i));
                    app.resultFullfile = fullfile(app.resultDir, [newName, '.csv']);
                else
                    break
                end
            end
            if strcmp(app.resultName,newName) == 0
                app.resultName = newName;
            end

            % Make subfolder for recordings, if relevant
            if strcmp(app.playbackMethod,'AudioToolbox')
                app.resultRecordingsDir = fullfile(app.resultDir,app.resultName);
                if ~isempty(app.playbackChannelsIn) && ~exist(app.resultRecordingsDir, 'dir')
                    mkdir(app.resultRecordingsDir);
                end
            end

            % ------------------------------------------------------------
            % EXPERIMENT SETTINGS

            if app.PercentCorrectButton.Value == 1; app.ResultType = 'Percent correct';
            else; app.ResultType = 'Speech reception threshold'; end

            if app.AudioPresentationButton.Value == 1; app.PresentationMode = 'Audio';
            else; app.PresentationMode = 'Audio-visual'; end

            if app.IntermittentButton.Value == 1; app.MaskerSettings = 'Intermittent';
            else; app.MaskerSettings = 'Continuous'; end 

            if app.KeywordBasedButton.Value == 1; app.ScoringType = 'Keyword-based';
            else; app.ScoringType = 'Word-by-word'; end

            % ------------------------------------------------------------
            % Calibration settings
            app.calibrationMeasuredLevel = str2num(string(app.CalibrationMeasuredEditField.Value)); % [sec] time between noise onset and sentence onset
            app.DurationBefore = double(string(app.DurationBeforeEditField.Value)); % [sec] time between noise onset and sentence onset
            if app.DurationBefore>0.5
                warning('Durations between the masker and sentence onsets of more than 500ms are currently unsupported. Using 500ms.');
                app.DurationBefore = 0.5;
            end
            
            % ------------------------------------------------------------
            % Playback settings

            % Handle radio buttons
            if app.MonoButton.Value == 1; app.playbackMode = 'Mono';
            elseif app.StereoButton.Value == 1; app.playbackMode = 'Stereo';
            else; app.playbackMode = 'Multi';
            end

            if app.BasicPlaybackButton.Value == 1; app.playbackMethod = 'Basic';
            elseif app.AudioToolboxButton.Value == 1; app.playbackMethod = 'AudioToolbox';
            else; app.playbackMethod = 'Psychtoolbox';
            end

            % Set up audio driver, etc.
            app.playbackDriver = app.DriverDropDown.Value;
            app.playbackChannelsOut = str2num(string(app.ChannelsOutEditField.Value));
            app.playbackChannelsIn = str2num(string(app.ChannelsInEditField.Value));

            switch app.playbackMethod

                case 'Basic'

                    % No setup needed

                case 'AudioToolbox' % Matlab Audio Toolbox

                    % Check if dsp object already exists
                    if ~isempty(app.playbackObjectPlayer)
                        release(app.playbackObjectPlayer);
                    end
                    if ~isempty(app.playbackObjectRecorder)
                        release(app.playbackObjectRecorder);
                    end

                    % Create the handle to the device writer
                    if ~isempty(app.playbackChannelsOut)
                        app.playbackObjectPlayer = audioDeviceWriter(app.Fs,...
                            'Driver',app.playbackDriver,...
                            'ChannelMappingSource','Property',...
                            'ChannelMapping',app.playbackChannelsOut,...
                            'BufferSize',app.playbackBuffer);  
                    end

                    % Set playback mode
                    if ~isempty(app.playbackChannelsOut)
                        if length(app.playbackChannelsOut)==1
                            app.playbackMode = 'mono';
                        elseif mod(app.playbackChannelsOut,2)==0
                            app.playbackMode = 'stereo';
                        else
                            app.playbackMode = 'multi';
                        end
                    end

                    % Create the handle to the device recorder, if relevant
                    if ~isempty(app.playbackChannelsIn)
                        app.playbackObjectRecorder = audioDeviceReader(app.Fs,...
                            'Driver',app.playbackDriver,...
                            'ChannelMappingSource','Property',...
                            'ChannelMapping',app.playbackChannelsIn,...
                            'SamplesPerFrame',app.playbackBuffer);
                    end

                    % Create the handle to the dsp.MatFileReader
                    app.playbackTemporaryFile = fullfile(app.resultDir,'DAST_temporary_file.mat');
                    app.playbackObjectFileReader = dsp.MatFileReader(app.playbackTemporaryFile,...
                        'VariableName','signalToPlay',...
                        'SamplesPerFrame',app.playbackBuffer);    

                case 'Psychtoolbox'

                    % TODO
            end

            % ------------------------------------------------------------

        end

        function soundViaAudioToolbox(app,signalToPlay)

            % Duplicate signalToPlay, if necessary
            if size(signalToPlay,2)~=length(app.playbackChannelsOut)
                if strcmp(app.playbackMode,'mono')
                    signalToPlay = repmat(signalToPlay, 1, ...
                        length(app.playbackChannelsOut));
                elseif mod(length(app.playbackChannelsOut),2)==0 % duplicate
                    signalToPlay = repmat(signalToPlay, 1, ...
                        length(app.playbackChannelsOut)/size(signalToPlay,2));
                else
                    % Something is wrong
                end
            end

            % Write temporary .mat file with the data
            save(app.playbackTemporaryFile,'signalToPlay','-v7.3');

            % Play sentence
            while ~isDone(app.playbackObjectFileReader)
                audio = step(app.playbackObjectFileReader);
                step(app.playbackObjectPlayer,audio);
            end
            release(app.playbackObjectFileReader);
            release(app.playbackObjectPlayer);
            delete(app.playbackTemporaryFile);

        end

        function recordViaAudioToolbox(app)

            % Prepare for recording
            app.playbackObjectFileWriter = dsp.AudioFileWriter(...
                fullfile(app.resultRecordingsDir,app.targetFile),...
                'FileFormat','WAV',...
                'SampleRate',app.Fs);

            % Enable continue button after end of sentence
            app.ContinueButton.Enable = "on";

            % Record the response
            pause(0);
            tic;
            recordingMaxSec = 60;
            app.recordingFlag = true;
            while app.recordingFlag==true && toc < recordingMaxSec
                signalRecorded = app.playbackObjectRecorder();
                app.playbackObjectFileWriter(signalRecorded);
                drawnow limitrate; % update process for pending callbacks
            end
            release(app.playbackObjectRecorder);
            release(app.playbackObjectFileWriter);

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Intializing variables
            [currentfilepath,~,~] = fileparts(mfilename('fullpath'));
            cd (currentfilepath)
            formatOut = 'yyyy_mm_dd';
            app.Date = datestr(now,formatOut);
            app.FilenameEditField.Value = sprintf(app.Date);
            app.currentAudioDir.Value = fullfile(pwd, 'audio');

            % disable buttons
            disableButtons_initialState(app)
            app.StartinglistDropDown.Visible = "off";
            app.StartinglistDropDownLabel.Visible = "off";

        end

        % Value changed function: FilenameEditField, 
        % ...and 2 other components
        function TestInfoChanged(app, event)
            if isempty(app.TesterIDEditField.Value); Leader = '';
            else; Leader = strcat('_', app.TesterIDEditField.Value); end
            if isempty(app.ParticipantIDEditField.Value); Subject = '';
            else; Subject = strcat(app.ParticipantIDEditField.Value, '_'); end

            app.FilenameEditField.Value = sprintf(strcat(Subject, app.Date, Leader));
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

%             try
                % Get information from user
                getSettings(app)

                % Enable buttons
                enableButtons_testState(app)
                app.Sentences.Text = 'Sentence';

                %--------------------------------------------------------
                % write basic information in csv
                file = fopen(app.resultFullfile, 'w');
                fprintf(file, "DAST result file\n");
                fprintf(file, "Filename, %s\n", app.resultName);
                fprintf(file, "Date and time, %s\n", datestr(now,'dd-mmm-yyyy HH:MM:SS'));
                fprintf(file, "Procedure:, %s\n", app.ResultType);
                fprintf(file, "Scoring, %s\n", app.ScoringType);
                fprintf(file, "Audio path, %s\n", app.currentAudioDir.Value);
                if app.AudioSettingsTabs.SelectedTab == app.AdvancedTab
                    fprintf(file, "Loaded CSV, %s\n", app.CSVfileEditField.Value);
                end
                fprintf(file, "\n");
                fprintf(file, "List,Target,Masker, Target level [dB], Masker level [dB], Score (%%)\n");
                fclose(file);

                %--------------------------------------------------------

                % Update files names
                app.scores = [];
                app.totalScore = 0;

                app.iteration = 0; % current sentence

                if app.AudioSettingsTabs.SelectedTab == app.AdvancedTab
                    chosenOption = find(app.lists == double(string(app.StartinglistDropDown.Value)));
                    app.list_iteration = chosenOption; % current list from dropdown
                else
                    app.list_iteration = 1; % current list
                end

                updateInformation(app);

                % Play sound
                pause(0.1);
                playSentence(app);

                %--------------------------------------------------------

%             catch
%                 if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab
%                     errordlg('Please check input parameters for quick start');
%                     disableButtons_initialState(app)
%                 elseif app.AudioSettingsTabs.SelectedTab == app.AdvancedTab
%                     errordlg('Please check input parameters for advanced options.');
%                     disableButtons_initialState(app)
%                 else
%                     errordlg('Please check input parameters.');
%                     disableButtons_initialState(app)
%                 end
%             end

        end

        % Value changed function: MarkallButton
        function MarkallButtonValueChanged(app, event)
            value = app.MarkallButton.Value;
            for i = 1:app.totButtons
                if app.(sprintf('W%iButton', i)).Visible == "on"
                    if (value == 1 && app.ScoringType ~= "Keyword-based") ||...
                            (value == 1 && app.ScoringType == "Keyword-based" && any(app.KeywordsButtons == i))
                        app.(sprintf('W%iButton', i)).Value = 1;
                    else
                        app.(sprintf('W%iButton', i)).Value = 0;
                    end
                end
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)

            app.ContinueButton.Enable = "off";

            % release audio objects
            releaseAudioObjs(app)

            %--------------------------------------------------------

            % calculate and save results in csv
            file = fopen(app.resultFullfile, 'a+');

            % Saving correctly repeated words in 0/1 array
            if app.ScoringType == "Keyword-based"
                wordsDisplay = strsplit(string(app.Keywords), ', ');
                numWordsSent = numel(wordsDisplay);
                wordsRating = zeros(1,numWordsSent);
                for j = 1:length(app.KeywordsButtons)
                    if app.(sprintf('W%iButton', app.KeywordsButtons(j))).Value == 1
                        wordsRating(j) = 1;
                    end
                end
            else
                wordsDisplay = strsplit(string(app.sentText));
                numWordsSent = numel(wordsDisplay);
                wordsRating = zeros(1,numWordsSent);
                for i = 1:app.totButtons
                    if app.(sprintf('W%iButton', i)).Value == 1
                        wordsRating(i) = 1;
                    end
                end
            end

            numCorrectWords = sum(wordsRating); 
            score = (numCorrectWords/numWordsSent)*100;
            
            if app.ResultType == "Precent correct"
                app.scores = [app.scores score];
                app.totalScore = round(mean(app.scores));
%             else % TODO, save final SRT
            end

            % Writing results
            totalStrings = join(repmat("%s",1,numWordsSent),',');
            totalRatings = join(repmat("%i",1,numWordsSent),',');

            % save first row of results with basic data
            fprintf(file, strcat("%i,%i,%s,%i,%i,%0.1f,", totalStrings, "\n"),...
                app.listNum, app.sent, app.maskerType, app.targetLevel, app.maskerLevel,...
                score,wordsDisplay);  

            % save second row of results with rating for words
            fprintf(file, strcat(",,,,,,", totalRatings, "\n"),wordsRating);
            fclose(file);
            %--------------------------------------------------------

            for i = 1:app.totButtons
                app.(sprintf('W%iButton', i)).Value = 0;
                app.(sprintf('W%iButton', i)).FontWeight = 'normal';
                app.(sprintf('W%iButton', i)).Enable = 'on';
            end
            app.KeywordsButtons = [];
            app.MarkallButton.Value = 0;

            if app.iteration < app.sentTot

                % next sentence
                %--------------------------------------------------------
                % Update file locations/names
                updateInformation(app);

                % Play sound
                pause(0.1);
                playSentence(app);
                %--------------------------------------------------------

            elseif app.list_iteration < app.listTot

                % Write current list results
                file = fopen(app.resultFullfile, 'a+');
                if strcmp(app.ResultType,'Percent correct')
                    fprintf(file, "%s,,,,,%0.1f%%\n", "Result", app.totalScore);
%                 else % TODO, save final SRT
%                     fprintf(file, "%s,,,,,%s\n", "Result", [num2str(app.totalScore), ' dB']);
                end
                fprintf(file, "\n");
                fclose(file);

                % Going to next list by pressing button
                app.ContinueButton.Enable = "off";
                pause(0.01)
                app.NextlistButton.Visible = "on";
                app.NextlistButton.Enable = "on";

                app.scores = [];
                app.totalScore = 0;

                app.Sentences.Text = ['List ', num2str(app.listNum), ' completed'];
                app.Sentences.FontSize = 20;

                % make all buttons invisible
                for button = 1:app.totButtons
                    app.(sprintf('W%iButton', button)).Visible = "off";
                end
                app.PauseButton.Enable = "off";
                app.MarkallButton.Visible = "off";
                app.Instructions.Visible = "off";

            else
                % end of test
                disableButtons_initialState(app);
                pause(0.01)
                app.Sentences.Visible = "on";
                app.Sentences.Text = ['List ', num2str(app.listNum), ' completed'];
                app.Sentences.FontSize = 20;

                % Write final results
                file = fopen(app.resultFullfile, 'a+');
                if strcmp(app.ResultType,'Percent correct')
                    fprintf(file, "%s,,,,,%0.1f%%\n", "Result", app.totalScore);
%                 else % TODO, save final SRT
%                     fprintf(file, "%s,,,,,%s\n", "Result", [num2str(app.totalScore), ' dB']);
                end
                fclose(file);
            end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            % release audio objects
            releaseAudioObjs(app)

            % disable buttons
            disableButtons_initialState(app)
            
            app.Sentences.Visible = "on";
            app.Sentences.Text = 'Test terminated';
            app.Sentences.FontSize = 20;

            % Write partial results
            if ~isempty(app.scores)
                file = fopen(app.resultFullfile, 'a+');
                if strcmp(app.ResultType,'Percent correct')
                    fprintf(file, "%s,,,,,%0.1f%%\n", "Result", app.totalScore);
%                 else % TODO, save final SRT
%                     fprintf(file, "%s,,,,,%s\n", "Result", [num2str(app.totalScore), ' dB']);
                end
                fprintf(file, "Measurement cancelled\n");
                fprintf(file, "\n");
                fclose(file);
            end
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)

            % Pressing pause
            if app.PauseButton.Value == 1

                % release audio objects
                releaseAudioObjs(app)

                app.Sentences.Text = 'Test paused';
                app.Sentences.FontSize = 20;

                % make buttons invisible
                % saving info on which are currently visible
                app.activeButtons = zeros(1, app.totButtons);
                for button = 1:app.totButtons
                    if app.(sprintf('W%iButton', button)).Visible == "on"
                        app.activeButtons(button) = 1;
                    end
                    app.(sprintf('W%iButton', button)).Visible = "off";
                end
                app.ContinueButton.Enable = "off";
                app.MarkallButton.Visible = "off";
                app.Instructions.Visible = "off";

            else
                % Pressing again for continuing test enabling buttons
                app.Sentences.Text = 'Sentence';
                app.Sentences.FontSize = 14;

                % make all buttons visible
                for button = 1:app.totButtons
                    if app.activeButtons(button) == 1
                        app.(sprintf('W%iButton', button)).Visible = "on";
                    end
                end
                app.MarkallButton.Visible = "on";
                app.Instructions.Visible = "on";

                % Playing sentence again
                pause(0.1);
                playSentence(app);

            end

        end

        % Value changed function: W1Button, W2Button, W3Button, W4Button, 
        % ...and 4 other components
        function wordSelected(app, event)
            values = zeros(1,app.totButtons);
            for i = 1:app.totButtons
                values(i) =  app.(sprintf('W%iButton', i)).Value;
            end

            if any(values == 0)
                app.MarkallButton.Value = 0;
            end

        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.csv');
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure)
                return
            else
                try
                    app.CSVfileEditField.Value = fullfile(pathname,filename);
                    app.UploadedCSVCheckBox.Value = 1;
                    app.StartinglistDropDownLabel.Visible = "on";
                    app.StartinglistDropDown.Visible = "on";

                    % Read from csv file
                    opts = detectImportOptions(app.CSVfileEditField.Value);
                    opts.Encoding = 'UTF-8';
                    opts.VariableNamingRule = 'Preserve';
                    app.sentencesFull = readtable(app.CSVfileEditField.Value, opts);  % uploaded csv with test info
                    app.lists = unique(app.sentencesFull.List, 'stable'); % get lists
                    app.listTot = length(app.lists);

                    % Showing list options in menu
                    app.StartinglistDropDown.Items = cellstr(num2str(app.lists));

                    drawnow;
                    figure(app.UIFigure)

                catch
                    figure(app.UIFigure)
                    app.CSVfileEditField.Value = '';
                    app.UploadedCSVCheckBox.Value = 0;
                    app.StartinglistDropDownLabel.Visible = "off";
                    app.StartinglistDropDown.Visible = "off";
                    errordlg('Invalid CSV file. Please upload a file with the right format.');
                    drawnow;
                    disableButtons_initialState(app)
                end

            end
        end

        % Button pushed function: ChooseAudioPathButton
        function ChooseAudioPathButtonPushed(app, event)
            audiopath = uigetdir;

            if audiopath==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure)
                return
            else
                app.currentAudioDir.Value = audiopath;
                drawnow;
                figure(app.UIFigure)
            end

        end

        % Value changed function: PlaycalibrationfileButton
        function PlaycalibrationfileButtonPushed(app, event)

            % Handle radio buttons
            if app.BasicPlaybackButton.Value == 1; app.playbackMethod = 'Basic';
            elseif app.AudioToolboxButton.Value == 1; app.playbackMethod = 'AudioToolbox';
            else; app.playbackMethod = 'Psychtoolbox';
            end

            value = app.PlaycalibrationfileButton.Value;

            if value == 0
                % if already pressed, stop sound
                clear sound

            else
                % if pressed, play tone

                % Read calibration signal (TODO Change this to select speaker-specific SSN)
                [calibrationSignal,calibrationFs] = audioread(fullfile(app.currentAudioDir.Value,'Noise','SSN_S.wav'));

                % Apply equalization filter, if relevant
                if ~isempty(app.equalizationFilter)
                    calibrationSignal = repmat(calibrationSignal,1,size(app.equalizationFilter,2));
                    for cc = 1:size(app.equalizationFilter,2)
                        calibrationSignal(:,cc) = filter(app.equalizationFilter(:,cc),1,calibrationSignal(:,cc));
                    end
                end

                % Play the calibration file
                switch app.playbackMethod

                    case 'Basic'

                        sound(calibrationSignal,calibrationFs);

                    case 'AudioToolbox'

                        soundViaAudioToolbox(app,calibrationSignal);

                    case 'Psychtoolbox'
                        
                        % TODO soundViaPsychtoolbox

                end

            end
            % TODO Add continuous looped playback

        end

        % Button pushed function: NextlistButton
        function NextlistButtonPushed(app, event)

            % if button pressed
            app.NextlistButton.Visible = "off";
            app.NextlistButton.Enable = "off";

            app.Sentences.Text = 'Sentence';
            app.Sentences.FontSize = 14;

            % make all buttons visible
            for button = 1:app.totButtons
                app.(sprintf('W%iButton', button)).Visible = "on";
            end
            app.ContinueButton.Enable = "off";
            app.PauseButton.Enable = "on";
            app.MarkallButton.Visible = "on";
            app.Instructions.Visible = "on";

            file = fopen(app.resultFullfile, 'a+');
            fprintf(file, "List,Target,Masker, Target level [dB],Masker level [dB], Score (%%)\n");
            fclose(file);

            app.list_iteration = app.list_iteration + 1;
            app.iteration = 0;

            %--------------------------------------------------------
            % Update file locations/names
            updateInformation(app);

            % Play sound
            pause(0.1);
            playSentence(app);
            %--------------------------------------------------------

        end

        % Selection changed function: PlaybackmethodButtonGroup
        function playbackMethodChanged(app, event)
            
            % Handle radio buttons
            if app.BasicPlaybackButton.Value == 1
                app.playbackMethod = 'Basic';

                app.DriverDropDown.Enable = "off";
                app.DriverDropDownLabel.Enable = "off";
                app.ChannelsOutEditField.Enable = "off";
                app.ChannelsOutEditFieldLabel.Enable = "off";
                app.ChannelsInEditField.Enable = "off";
                app.ChannelsInEditFieldLabel.Enable = "off";

            elseif app.AudioToolboxButton.Value == 1
                app.playbackMethod = 'AudioToolbox';

                app.DriverDropDown.Enable = "on";
                app.DriverDropDownLabel.Enable = "on";
                app.ChannelsOutEditField.Enable = "on";
                app.ChannelsOutEditFieldLabel.Enable = "on";
                app.ChannelsInEditField.Enable = "on";
                app.ChannelsInEditFieldLabel.Enable = "on";

                if ispc % if windows
                    app.DriverDropDown.Items = {'ASIO', 'DirectSound', 'WASAPI'};
                elseif ismac % if mac
                    app.DriverDropDown.Items = {'CoreAudio'};
                else % if linux (isunix)
                    app.DriverDropDown.Items = {'ALSA'};
                end
            else
                app.playbackMethod = 'Psychtoolbox';

                app.DriverDropDown.Enable = "off";
                app.DriverDropDownLabel.Enable = "off";
                app.ChannelsOutEditField.Enable = "off";
                app.ChannelsOutEditFieldLabel.Enable = "off";
                app.ChannelsInEditField.Enable = "off";
                app.ChannelsInEditFieldLabel.Enable = "off";
            end
        end

        % Selection changed function: PresentationmodeButtonGroup
        function presentationModeChanged(app, event)
            if app.AudioPresentationButton.Value == 1
                app.presentationMode = 'Audio';
                app.VideosetupButton.Enable = "off";
            else
                app.presentationMode = 'Audiovisual';
                app.VideosetupButton.Enable = "on";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the window?', 'Close request');
            if strcmpi(YN,'OK')

                % release audio objects
                releaseAudioObjs(app)

                % Delete the app
                delete(app);
            end
        end

        % Button pushed function: ImportequalizationfilterButton
        function ImportequalizationfilterButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.mat');
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                app.FilterEditField.Value = '';
                return
            else
                app.equalizationFilter = load(fullfile(pathname,filename));
                app.FilterEditField.Value = filename;
    
                drawnow;
                figure(app.UIFigure);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BasicinformationPanel = uipanel(app.GridLayout);
            app.BasicinformationPanel.Title = 'Basic information';
            app.TesterIDEditFieldLabel = uilabel(app.BasicinformationPanel);
            app.TesterIDEditFieldLabel.Text = 'Tester ID';
            app.TesterIDEditField = uieditfield(app.BasicinformationPanel, 'text');
            app.TesterIDEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.ParticipantIDEditFieldLabel = uilabel(app.BasicinformationPanel);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDEditField = uieditfield(app.BasicinformationPanel, 'text');
            app.ParticipantIDEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.FilenameEditFieldLabel = uilabel(app.BasicinformationPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.BasicinformationPanel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.FilenameEditField.Editable = 'off';
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Tag = 'SettingsPanel';
            app.AudioSettingsTabs = uitabgroup(app.Panel);
            app.QuickstartTab = uitab(app.AudioSettingsTabs);
            app.QuickstartTab.Title = 'Quick start';
            app.dBSPLLabel = uilabel(app.QuickstartTab);
            app.dBSPLLabel.Text = 'dB SPL';
            app.dBSPLLabel_2 = uilabel(app.QuickstartTab);
            app.dBSPLLabel_2.Text = 'dB SPL';
            app.TalkerDropDownLabel = uilabel(app.QuickstartTab);
            app.TalkerDropDownLabel.Text = 'Talker';
            app.TalkerDropDown = uidropdown(app.QuickstartTab);
            app.TalkerDropDown.Items = {'Female 1', 'Female 2', 'Male 1', 'Male 2', 'Random talker', 'Mixed talkers'};
            app.TalkerDropDown.Value = 'Female 1';
            app.ListnumberDropDownLabel = uilabel(app.QuickstartTab);
            app.ListnumberDropDownLabel.Text = 'List number';
            app.ListnumberDropDown = uidropdown(app.QuickstartTab);
            app.ListnumberDropDown.Items = {'List 1', 'List 2', 'List 3', 'List 4', 'List 5', 'List 6', 'List 7', 'List 8', 'List 9', 'List 10', 'Random list', 'Mixed list'};
            app.ListnumberDropDown.Value = 'List 1';
            app.MaskertypeDropDownLabel = uilabel(app.QuickstartTab);
            app.MaskertypeDropDownLabel.Text = 'Masker type';
            app.MaskertypeDropDown = uidropdown(app.QuickstartTab);
            app.MaskertypeDropDown.Items = {'SSN', 'Unmodulated', 'Modulated', 'Sinusoidal', '4T-Babble'};
            app.MaskertypeDropDown.Value = 'SSN';
            app.TargetlevelEditFieldLabel = uilabel(app.QuickstartTab);
            app.TargetlevelEditFieldLabel.Text = 'Target level';
            app.TargetlevelEditField = uieditfield(app.QuickstartTab, 'text');
            app.TargetlevelEditField.Value = '70';
            app.MaskerlevelEditFieldLabel = uilabel(app.QuickstartTab);
            app.MaskerlevelEditFieldLabel.Text = 'Masker level';
            app.MaskerlevelEditField = uieditfield(app.QuickstartTab, 'text');
            app.MaskerlevelEditField.Value = '70';
            app.RandomizewithinlistCheckBox = uicheckbox(app.QuickstartTab);
            app.RandomizewithinlistCheckBox.Text = 'Randomize within list';
            app.AdvancedTab = uitab(app.AudioSettingsTabs);
            app.AdvancedTab.Title = 'Advanced';
            app.UploadButton = uibutton(app.AdvancedTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.CSVfileEditFieldLabel = uilabel(app.AdvancedTab);
            app.CSVfileEditFieldLabel.Text = 'CSV file';
            app.CSVfileEditField = uieditfield(app.AdvancedTab, 'text');
            app.CSVfileEditField.Editable = 'off';
            app.PleaseuploadacsvfilewiththecorrectformatLabel = uilabel(app.AdvancedTab);
            app.PleaseuploadacsvfilewiththecorrectformatLabel.Text = {'Upload a csv file with the settings for the personalized test in the correct format.'; 'Obs. one CSV file per Subject No.'};
            app.UploadedCSVCheckBox = uicheckbox(app.AdvancedTab);
            app.UploadedCSVCheckBox.Enable = 'off';
            app.UploadedCSVCheckBox.Text = 'CheckUploadedCSV';
            app.StartinglistDropDownLabel = uilabel(app.AdvancedTab);
            app.StartinglistDropDownLabel.Text = 'Starting list';
            app.StartinglistDropDown = uidropdown(app.AdvancedTab);
            app.StartinglistDropDown.Items = {};
            app.StartinglistDropDown.Value = {};
            app.ChooseAudioPathButton = uibutton(app.Panel, 'push');
            app.ChooseAudioPathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseAudioPathButtonPushed, true);
            app.ChooseAudioPathButton.Text = 'Choose';
            app.AudiopathEditFieldLabel = uilabel(app.Panel);
            app.AudiopathEditFieldLabel.Text = 'Audio path';
            app.currentAudioDir = uieditfield(app.Panel, 'text');
            app.currentAudioDir.Editable = 'off';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.StartButton = uibutton(app.TestTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.TestTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DanskStningstestLabel = uilabel(app.TestTab);
            app.DanskStningstestLabel.Text = 'Dansk Sætningstest';
            app.ContinueButton = uibutton(app.TestTab, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.currentList = uilabel(app.TestTab);
            app.currentList.Tag = 'currentList';
            app.currentList.Text = 'currentList';
            app.currentSent = uilabel(app.TestTab);
            app.currentSent.Tag = 'currentSent';
            app.currentSent.Text = 'currentSent';
            app.Sentences = uilabel(app.TestTab);
            app.Sentences.Text = 'Sentence';
            app.Instructions = uilabel(app.TestTab);
            app.Instructions.Text = 'Mark correctly repeated words';
            app.MarkallButton = uibutton(app.TestTab, 'state');
            app.MarkallButton.ValueChangedFcn = createCallbackFcn(app, @MarkallButtonValueChanged, true);
            app.MarkallButton.Text = 'Mark all';
            app.PauseButton = uibutton(app.TestTab, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.W1Button = uibutton(app.TestTab, 'state');
            app.W1Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W1Button.Text = 'W1';
            app.W2Button = uibutton(app.TestTab, 'state');
            app.W2Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W2Button.Text = 'W2';
            app.W3Button = uibutton(app.TestTab, 'state');
            app.W3Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W3Button.Text = 'W3';
            app.W4Button = uibutton(app.TestTab, 'state');
            app.W4Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W4Button.Text = 'W4';
            app.W5Button = uibutton(app.TestTab, 'state');
            app.W5Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W5Button.Text = 'W5';
            app.W6Button = uibutton(app.TestTab, 'state');
            app.W6Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W6Button.Text = 'W6';
            app.W7Button = uibutton(app.TestTab, 'state');
            app.W7Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W7Button.Text = 'W7';
            app.W8Button = uibutton(app.TestTab, 'state');
            app.W8Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W8Button.Text = 'W8';
            app.W9Button = uibutton(app.TestTab, 'state');
            app.W9Button.Text = 'W9';
            app.W10Button = uibutton(app.TestTab, 'state');
            app.W10Button.Text = 'W10';
            app.W11Button = uibutton(app.TestTab, 'state');
            app.W11Button.Text = 'W11';
            app.NextlistButton = uibutton(app.TestTab, 'push');
            app.NextlistButton.ButtonPushedFcn = createCallbackFcn(app, @NextlistButtonPushed, true);
            app.NextlistButton.Text = 'Next list';
            app.W12Button = uibutton(app.TestTab, 'state');
            app.W12Button.Text = 'W12';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsPanelinSettingsTab = uipanel(app.SettingsTab);
            app.SettingsPanelinSettingsTab.Tag = 'Panel for more test settings';
            app.ResulttypeButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.ResulttypeButtonGroup.Title = 'Result type';
            app.PercentCorrectButton = uiradiobutton(app.ResulttypeButtonGroup);
            app.PercentCorrectButton.Text = 'Percent correct';
            app.PercentCorrectButton.Value = true;
            app.SpeechReceptionThresholdButton = uiradiobutton(app.ResulttypeButtonGroup);
            app.SpeechReceptionThresholdButton.Enable = 'off';
            app.SpeechReceptionThresholdButton.Text = 'Speech reception threshold';
            app.CalibrationButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.CalibrationButtonGroup.Title = 'Calibration';
            app.dBZLabel = uilabel(app.CalibrationButtonGroup);
            app.dBZLabel.Text = 'dB(Z)';
            app.CalibrationMeasuredEditField = uieditfield(app.CalibrationButtonGroup, 'text');
            app.CalibrationMeasuredEditField.Value = '95';
            app.MeasuredLabel = uilabel(app.CalibrationButtonGroup);
            app.MeasuredLabel.Text = 'Measured';
            app.PlaycalibrationfileButton = uibutton(app.CalibrationButtonGroup, 'state');
            app.PlaycalibrationfileButton.ValueChangedFcn = createCallbackFcn(app, @PlaycalibrationfileButtonPushed, true);
            app.PlaycalibrationfileButton.Text = 'Play calibration file';
            app.FilterEditFieldLabel = uilabel(app.CalibrationButtonGroup);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.FilterEditField = uieditfield(app.CalibrationButtonGroup, 'text');
            app.FilterEditField.Editable = 'off';
            app.ImportequalizationfilterButton = uibutton(app.CalibrationButtonGroup, 'push');
            app.ImportequalizationfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ImportequalizationfilterButtonPushed, true);
            app.ImportequalizationfilterButton.Text = 'Import equalization filter';
            app.PresentationmodeButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.PresentationmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @presentationModeChanged, true);
            app.PresentationmodeButtonGroup.Title = 'Presentation mode';
            app.AudioPresentationButton = uiradiobutton(app.PresentationmodeButtonGroup);
            app.AudioPresentationButton.Text = 'Audio';
            app.AudioPresentationButton.Value = true;
            app.AudioVisualPresentationButton = uiradiobutton(app.PresentationmodeButtonGroup);
            app.AudioVisualPresentationButton.Enable = 'off';
            app.AudioVisualPresentationButton.Text = 'Audio-visual';
            app.ScoringtypeButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.ScoringtypeButtonGroup.Title = 'Scoring type';
            app.KeywordBasedButton = uiradiobutton(app.ScoringtypeButtonGroup);
            app.KeywordBasedButton.Text = 'Keyword-based';
            app.WordByWordButton = uiradiobutton(app.ScoringtypeButtonGroup);
            app.WordByWordButton.Text = 'Word-by-word';
            app.WordByWordButton.Value = true;
            app.MaskersettingsButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.MaskersettingsButtonGroup.Title = 'Masker settings';
            app.ContinuousButton = uiradiobutton(app.MaskersettingsButtonGroup);
            app.ContinuousButton.Enable = 'off';
            app.ContinuousButton.Text = 'Continuous';
            app.IntermittentButton = uiradiobutton(app.MaskersettingsButtonGroup);
            app.IntermittentButton.Text = 'Intermittent';
            app.IntermittentButton.Value = true;
            app.secLabel = uilabel(app.MaskersettingsButtonGroup);
            app.secLabel.Text = 'sec';
            app.DurationbeforeandaftersentencesLabel = uilabel(app.MaskersettingsButtonGroup);
            app.DurationbeforeandaftersentencesLabel.Text = 'Duration before:';
            app.DurationBeforeEditField = uieditfield(app.MaskersettingsButtonGroup, 'text');
            app.DurationBeforeEditField.Value = '0.5';
            app.PlaybackmethodButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.PlaybackmethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @playbackMethodChanged, true);
            app.PlaybackmethodButtonGroup.Title = 'Playback method';
            app.ChannelsOutEditFieldLabel = uilabel(app.PlaybackmethodButtonGroup);
            app.ChannelsOutEditFieldLabel.Enable = 'off';
            app.ChannelsOutEditFieldLabel.Text = 'Channel(s) out';
            app.ChannelsOutEditField = uieditfield(app.PlaybackmethodButtonGroup, 'text');
            app.ChannelsOutEditField.Enable = 'off';
            app.ChannelsOutEditField.Value = '3:6';
            app.DriverDropDown = uidropdown(app.PlaybackmethodButtonGroup);
            app.DriverDropDown.Items = {'ASIO', 'DirectSound', 'WASAPI', 'ALSA', 'CoreAudio'};
            app.DriverDropDown.Enable = 'off';
            app.DriverDropDown.Value = 'ASIO';
            app.DriverDropDownLabel = uilabel(app.PlaybackmethodButtonGroup);
            app.DriverDropDownLabel.Enable = 'off';
            app.DriverDropDownLabel.Text = 'Driver';
            app.PsychtoolboxButton = uiradiobutton(app.PlaybackmethodButtonGroup);
            app.PsychtoolboxButton.Enable = 'off';
            app.PsychtoolboxButton.Text = 'Psychtoolbox';
            app.AudioToolboxButton = uiradiobutton(app.PlaybackmethodButtonGroup);
            app.AudioToolboxButton.Text = 'Audio Toolbox';
            app.BasicPlaybackButton = uiradiobutton(app.PlaybackmethodButtonGroup);
            app.BasicPlaybackButton.Text = 'Basic';
            app.BasicPlaybackButton.Value = true;
            app.ChannelsInEditFieldLabel = uilabel(app.PlaybackmethodButtonGroup);
            app.ChannelsInEditFieldLabel.Enable = 'off';
            app.ChannelsInEditFieldLabel.Text = 'Channel(s) in';
            app.ChannelsInEditField = uieditfield(app.PlaybackmethodButtonGroup, 'text');
            app.ChannelsInEditField.Enable = 'off';
            app.ChannelsInEditField.Value = '10';
            app.PresetButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.PresetButtonGroup.Title = 'Preset';
            app.OpenPresentButton = uibutton(app.PresetButtonGroup, 'push');
            app.OpenPresentButton.Enable = 'off';
            app.OpenPresentButton.Text = 'Open';
            app.currentPresent = uieditfield(app.PresetButtonGroup, 'text');
            app.currentPresent.Editable = 'off';
            app.currentPresent.Enable = 'off';
            app.SavePresentButton = uibutton(app.PresetButtonGroup, 'push');
            app.SavePresentButton.Enable = 'off';
            app.SavePresentButton.Text = 'Save';
            app.PlaybackmodeButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.PlaybackmodeButtonGroup.Title = 'Playback mode';
            app.StereoButton = uiradiobutton(app.PlaybackmodeButtonGroup);
            app.StereoButton.Text = 'Stereo';
            app.MonoButton = uiradiobutton(app.PlaybackmodeButtonGroup);
            app.MonoButton.Text = 'Mono';
            app.MonoButton.Value = true;
            app.MultiButton = uiradiobutton(app.PlaybackmodeButtonGroup);
            app.MultiButton.Enable = 'off';
            app.MultiButton.Text = 'Multi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end